classdef JuliaSet < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure       matlab.ui.Figure
        DoneButton     matlab.ui.control.Button
        EditField      matlab.ui.control.NumericEditField
        EditField2     matlab.ui.control.NumericEditField
        PleaseenteracomplexnumberCLabel  matlab.ui.control.Label
        Label          matlab.ui.control.Label
        iLabel         matlab.ui.control.Label
        JuliaSetLabel  matlab.ui.control.Label
        Image          matlab.ui.control.Image
        Copyright2021LinKAllrightsreservedLabel  matlab.ui.control.Label
    end

    
    properties (Access = private)
        Complex_a % Description
        Complex_b % Description
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: DoneButton
        function DoneButtonPushed(app, event)
            N = -2:0.01:2;
            [Re,Im] = meshgrid(N);
            Z = Re+Im*1i;
            C = app.Complex_b*1i+app.Complex_a;
            J = 0;
            for n = 1:25
                J = J+(abs(Z)<=2);
                Z = Z.^2+C;
                imagesc(J);
                colormap('jet');
                axis equal
                axis off
                drawnow;
                pause(0.1)
            end
        end

        % Value changed function: EditField
        function EditFieldValueChanged(app, event)
            value = app.EditField.Value;
            app.Complex_a=value;
        end

        % Value changed function: EditField2
        function EditField2ValueChanged(app, event)
            value = app.EditField2.Value;
            app.Complex_b=value;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.DoneButton = uibutton(app.UIFigure, 'push');
            app.DoneButton.ButtonPushedFcn = createCallbackFcn(app, @DoneButtonPushed, true);
            app.DoneButton.Text = 'Done';
            app.EditField = uieditfield(app.UIFigure, 'numeric');
            app.EditField.ValueChangedFcn = createCallbackFcn(app, @EditFieldValueChanged, true);
            app.EditField2 = uieditfield(app.UIFigure, 'numeric');
            app.EditField2.ValueChangedFcn = createCallbackFcn(app, @EditField2ValueChanged, true);
            app.PleaseenteracomplexnumberCLabel = uilabel(app.UIFigure);
            app.PleaseenteracomplexnumberCLabel.Text = 'Please enter a complex number C :';
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '+';
            app.iLabel = uilabel(app.UIFigure);
            app.iLabel.Text = 'i';
            app.JuliaSetLabel = uilabel(app.UIFigure);
            app.JuliaSetLabel.Text = 'Julia Set';
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageSource = 'Julia.jpg';
            app.Copyright2021LinKAllrightsreservedLabel = uilabel(app.UIFigure);
            app.Copyright2021LinKAllrightsreservedLabel.Text = 'Copyright © 2021 Lin.K. All rights reserved.';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = JuliaSet

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end